classdef appContolSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectButton          matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        EneableDiseablePanel   matlab.ui.container.Panel
        AlarmSwitch            matlab.ui.control.Switch
        AlarmSwitchLabel       matlab.ui.control.Label
        LaserButton            matlab.ui.control.StateButton
        DisplayButton          matlab.ui.control.StateButton
        PanicButton            matlab.ui.control.StateButton
        ChangePasswordPanel    matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        Newpass                matlab.ui.control.EditField
        NewpassEditFieldLabel  matlab.ui.control.Label
        Oldpass                matlab.ui.control.EditField
        OldpassEditFieldLabel  matlab.ui.control.Label
        PleaseenterthenewpasswordoncetoverifyLabel  matlab.ui.control.Label
        verify                 matlab.ui.control.EditField
        verifyEditFieldLabel   matlab.ui.control.Label
        ConfirmButton          matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        SecurityControlLabel   matlab.ui.control.Label
    end

    properties (Access = public)

        comu; % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.comu = serial('COM4', 'BaudRate', 9600);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            fclose(app.comu);
            delete(app.comu);
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            fopen(app.comu);
        end

        % Value changed function: PanicButton
        function PanicButtonValueChanged(app, event)
            value = app.PanicButton.Value;

            if value == 1
                fprintf(app.comu, 'p');
                fprintf(app.comu, 'p');
                fprintf(app.comu, 'p');
                fprintf(app.comu, 'p');
                fprintf(app.comu, 'p');
            else value == 0
                fprintf(app.comu, 'o');
             
            end
         
        end

        % Value changed function: LaserButton
        function LaserButtonValueChanged(app, event)
            value = app.LaserButton.Value;
            if value == 1
                fprintf(app.comu, 'j')
            else value == 0
                fprintf(app.comu, 'l');
            end
        end

        % Callback function: not associated with a component
        function DisconnectButtonPushed(app, event)
            fclose(app.comu);
            delete(app.comu);
        end

        % Value changed function: DisplayButton
        function DisplayButtonValueChanged(app, event)
            value = app.DisplayButton.Value;
            if value == 1
                fprintf(app.comu, 'y')
            else value == 0
                fprintf(app.comu, 'x');
            end
        end

        % Value changed function: AlarmSwitch
        function AlarmSwitchValueChanged(app, event)
            value = app.AlarmSwitch.Value;
            if strcmp(value,'Disarm')
                fprintf(app.comu, 'j');
                fprintf(app.comu, 'o');
            end
            if strcmp(value,'Arm')
                fprintf(app.comu, 'l');
                fprintf(app.comu, 'p');
            end

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            tempo1 = app.Newpass.Value;
            tempo2 = app.verify.Value;

            if strcmp(tempo1, tempo2)
                clavevieja=app.Oldpass.Value;
                fprintf(app.comu,'z');
                clavearduino= fscanf(app.comu, '%s');
                clavearduino= fscanf(app.comu, '%s');
                if strcmp(clavearduino, clavevieja);
                    app.Label.Text = 'contraseña actualizada';
                    fprintf(app.comu,'e');
                    fprintf(app.comu, '%d', str2num(tempo2),'sync');

                else 
                    app.Label.Text = 'CLAVE ANTIGUA ERRONEA';
                end    
            else
                app.Label.Text ='ERROR, VERIQUE DE NUEVO, NO SON IGUALES';
            end
     
            
            %fprintf(app.comu,'z');
            %set(app.Label, 'Text', fscanf(app.comu, '%s'));
            %set(app.Label, 'Text', fscanf(app.comu, '%s'));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SecurityControlLabel = uilabel(app.UIFigure);
            app.SecurityControlLabel.Text = 'Security Control ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChangePasswordPanel = uipanel(app.UIFigure);
            app.ChangePasswordPanel.Title = 'Change Password';
            app.ConfirmButton = uibutton(app.ChangePasswordPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.verifyEditFieldLabel = uilabel(app.ChangePasswordPanel);
            app.verifyEditFieldLabel.Text = 'verify ';
            app.verify = uieditfield(app.ChangePasswordPanel, 'text');
            app.PleaseenterthenewpasswordoncetoverifyLabel = uilabel(app.ChangePasswordPanel);
            app.PleaseenterthenewpasswordoncetoverifyLabel.Text = 'Please enter the new password once to verify';
            app.OldpassEditFieldLabel = uilabel(app.ChangePasswordPanel);
            app.OldpassEditFieldLabel.Text = 'Old pass';
            app.Oldpass = uieditfield(app.ChangePasswordPanel, 'text');
            app.NewpassEditFieldLabel = uilabel(app.ChangePasswordPanel);
            app.NewpassEditFieldLabel.Text = 'New pass';
            app.Newpass = uieditfield(app.ChangePasswordPanel, 'text');
            app.Label = uilabel(app.ChangePasswordPanel);
            app.Label.Text = '';
            app.EneableDiseablePanel = uipanel(app.UIFigure);
            app.EneableDiseablePanel.Title = 'Eneable / Diseable';
            app.PanicButton = uibutton(app.EneableDiseablePanel, 'state');
            app.PanicButton.ValueChangedFcn = createCallbackFcn(app, @PanicButtonValueChanged, true);
            app.PanicButton.Text = 'Panic';
            app.DisplayButton = uibutton(app.EneableDiseablePanel, 'state');
            app.DisplayButton.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonValueChanged, true);
            app.DisplayButton.Text = 'Display';
            app.LaserButton = uibutton(app.EneableDiseablePanel, 'state');
            app.LaserButton.ValueChangedFcn = createCallbackFcn(app, @LaserButtonValueChanged, true);
            app.LaserButton.Text = 'Laser';
            app.AlarmSwitchLabel = uilabel(app.EneableDiseablePanel);
            app.AlarmSwitchLabel.Text = 'Alarm';
            app.AlarmSwitch = uiswitch(app.EneableDiseablePanel, 'slider');
            app.AlarmSwitch.Items = {'Disarm', 'Arm'};
            app.AlarmSwitch.ValueChangedFcn = createCallbackFcn(app, @AlarmSwitchValueChanged, true);
            app.AlarmSwitch.Value = 'Arm';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appContolSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end